classdef ProjectPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        RootTab           matlab.ui.container.Tab
        InterpolationTab  matlab.ui.container.Tab
        RegressTab        matlab.ui.container.Tab
        DerivativeTab     matlab.ui.container.Tab
        IntegralTab       matlab.ui.container.Tab
        IntroduceTab      matlab.ui.container.Tab
        HEYYOUVEGOTSOMEFRIENDSREQUESTLabel  matlab.ui.control.Label
        TeamLogo          matlab.ui.control.Image
        ClickhereButton   matlab.ui.control.Button
        UITable           matlab.ui.control.Table
        GroupName         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClickhereButton
        function ClickhereButtonPushed(app, event)
            app.GroupName.Visible = 'on';
            app.TeamLogo.Visible = 'on';
            app.UITable.Visible = 'on';
            app.ClickhereButton.Visible = 'off';
            app.HEYYOUVEGOTSOMEFRIENDSREQUESTLabel.Visible = 'off';
            Name = {'Lê Ngọc Long';'Lý Minh Mẫn';'Đoàn Nhật Ninh';'Nguyễn Tấn Phát';'Vũ Hưng Phát'};
            ID = [21200310 21200311 21200325 21200326 21200327]';
            Position = {'Leader';'Assistant';'Secretary';'Advisor';'UI Designer'};
            T = table(Name,ID,Position);
            app.UITable.Data=T;
            app.UITable.ColumnName=T.Properties.VariableNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RootTab = uitab(app.TabGroup);
            app.RootTab.Title = 'Nghiệm';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Nội suy';
            app.RegressTab = uitab(app.TabGroup);
            app.RegressTab.Title = 'Hồi quy';
            app.DerivativeTab = uitab(app.TabGroup);
            app.DerivativeTab.Title = 'Đạo hàm';
            app.IntegralTab = uitab(app.TabGroup);
            app.IntegralTab.Title = 'Tích phân';
            app.IntroduceTab = uitab(app.TabGroup);
            app.IntroduceTab.Title = 'Giới thiệu nhóm';
            app.GroupName = uilabel(app.IntroduceTab);
            app.GroupName.Visible = 'off';
            app.GroupName.Text = 'Hello! We''re the "Ghệ Đẹp" team!';
            app.UITable = uitable(app.IntroduceTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ClickhereButton = uibutton(app.IntroduceTab, 'push');
            app.ClickhereButton.ButtonPushedFcn = createCallbackFcn(app, @ClickhereButtonPushed, true);
            app.ClickhereButton.Icon = 'alarm.gif';
            app.ClickhereButton.Text = 'Click here!';
            app.TeamLogo = uiimage(app.IntroduceTab);
            app.TeamLogo.Visible = 'off';
            app.TeamLogo.ImageSource = 'waving.gif';
            app.HEYYOUVEGOTSOMEFRIENDSREQUESTLabel = uilabel(app.IntroduceTab);
            app.HEYYOUVEGOTSOMEFRIENDSREQUESTLabel.Text = 'HEY! YOU''VE GOT SOME FRIENDS REQUEST ->';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end