classdef ProjectPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        RootTab                     matlab.ui.container.Tab
        InterpolationTab            matlab.ui.container.Tab
        RegressTab                  matlab.ui.container.Tab
        DerivativeTab               matlab.ui.container.Tab
        InputMethodDeriButtonGroup  matlab.ui.container.ButtonGroup
        InputFuncButton             matlab.ui.control.RadioButton
        InputArrayButton            matlab.ui.control.RadioButton
        x0EditField                 matlab.ui.control.NumericEditField
        Gitrx0EditFieldLabel        matlab.ui.control.Label
        DeriMethodButtonGroup       matlab.ui.container.ButtonGroup
        HngtmButton                 matlab.ui.control.RadioButton
        XpxliButton                 matlab.ui.control.RadioButton
        XpxtinButton                matlab.ui.control.RadioButton
        SaiSoButtonGroup            matlab.ui.container.ButtonGroup
        Oh2Button                   matlab.ui.control.RadioButton
        OhButton                    matlab.ui.control.RadioButton
        UsingFuncPanel              matlab.ui.container.Panel
        Ex_Label2                   matlab.ui.control.Label
        hInput_DeriEditField        matlab.ui.control.EditField
        NhpbchLabel                 matlab.ui.control.Label
        fInput_DeriEditField        matlab.ui.control.EditField
        NhphmsLabel                 matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        fx0EditField                matlab.ui.control.EditField
        fx0EditFieldLabel           matlab.ui.control.Label
        UsingIntepolatePanel        matlab.ui.container.Panel
        Ex_Label1                   matlab.ui.control.Label
        yInput_DeriEditField        matlab.ui.control.EditField
        NhpyLabel                   matlab.ui.control.Label
        xInput_DeriEditField        matlab.ui.control.EditField
        NhpxEditFieldLabel          matlab.ui.control.Label
        IntegralTab                 matlab.ui.container.Tab
        Func_IntPanel               matlab.ui.container.Panel
        Vdy2x232xLabel              matlab.ui.control.Label
        NhphmEditField              matlab.ui.control.EditField
        NhphmEditFieldLabel         matlab.ui.control.Label
        Interpolate_IntPanel        matlab.ui.container.Panel
        VdX123tanhp123Label         matlab.ui.control.Label
        yInputArray_IntEditField    matlab.ui.control.EditField
        NhpmngYLabel                matlab.ui.control.Label
        xInputArray_IntEditField    matlab.ui.control.EditField
        NhpmngXLabel                matlab.ui.control.Label
        ThngsPanel                  matlab.ui.container.Panel
        enterNEditField             matlab.ui.control.NumericEditField
        enterNEditFieldLabel        matlab.ui.control.Label
        enterbEditField             matlab.ui.control.NumericEditField
        enterbEditFieldLabel        matlab.ui.control.Label
        enteraEditField             matlab.ui.control.NumericEditField
        enteraEditFieldLabel        matlab.ui.control.Label
        resultTextArea              matlab.ui.control.TextArea
        resultTextAreaLabel         matlab.ui.control.Label
        IntMethodButtonGroup        matlab.ui.container.ButtonGroup
        Simpson3_8Button            matlab.ui.control.RadioButton
        SimpsonButton               matlab.ui.control.RadioButton
        hinhthangButton             matlab.ui.control.RadioButton
        InputMethodIntButtonGroup   matlab.ui.container.ButtonGroup
        FuncInput_IntButton         matlab.ui.control.RadioButton
        ArrayInput_IntButton        matlab.ui.control.RadioButton
        IntegralcalculationButton   matlab.ui.control.Button
        IntroduceTab                matlab.ui.container.Tab
        THISISAPRODUCTOFGHEDEPTEAMFORMOREINFOCLICKONTHEBUTTONBELOWLabel  matlab.ui.control.Label
        TeamLogo                    matlab.ui.control.Image
        ClickhereButton             matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        GroupName                   matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function result = Lagrangev2(app, xa, ya)
                syms x;
                n = length(xa);
                sum(x) = 0*x;
                for i=1:n
                   product(x) = ya(i)*x/x;
                   for j=1:n
                       if(i~=j)
                           product(x) = product(x)*(x - xa(j))/(xa(i) - xa(j));
                       end
                   end
                   sum(x) = sum(x) + product(x);
                end
                result(x) = sum(x);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClickhereButton
        function ClickhereButtonPushed(app, event)
            app.GroupName.Visible = 'on';
            app.TeamLogo.Visible = 'on';
            app.UITable.Visible = 'on';
            app.ClickhereButton.Visible = 'off';
            app.THISISAPRODUCTOFGHEDEPTEAMFORMOREINFOCLICKONTHEBUTTONBELOWLabel.Visible = 'off';
            Name = {'Lê Ngọc Long';'Lý Minh Mẫn';'Đoàn Nhật Ninh';'Nguyễn Tấn Phát';'Vũ Hưng Phát'};
            ID = [21200310 21200311 21200325 21200326 21200327]';
            Position = {'Leader';'Assistant';'Secretary';'Advisor';'UI Designer'};
            T = table(Name,ID,Position);
            app.UITable.Data=T;
            app.UITable.ColumnName=T.Properties.VariableNames;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            x0 = app.x0EditField.Value;
            switch app.InputMethodDeriButtonGroup.SelectedObject.Text
                case 'Nhập mảng'
%                     xArray = [0.1 0.3 0.5 0.7 0.9];
%                     yArray = [0.1002 0.3047 0.5236 0.7754 1.1198];
                    xInput = app.xInput_DeriEditField.Value;
                    xArray = str2num(xInput);            
                    yInput = app.yInput_DeriEditField.Value;
                    yArray = str2num(yInput);
                    syms x
                    f(x) = Lagrangev2(app, xArray, yArray);
                    h = xArray(2) - xArray(1);                  %voi day nhap vao la day cach deu mot khoang h

                otherwise
                    f = char(app.fInput_DeriEditField.Value);
                    f = str2func(['@(x)', f]);
                    h = str2double(app.hInput_DeriEditField.Value);
            end
            
            choice_1 = app.DeriMethodButtonGroup.SelectedObject.Text;
            if(strcmp(choice_1,'Xấp xỉ tiến'))
                method = 1;
            elseif(strcmp(choice_1,'Xấp xỉ lùi'))
                method = 2;
            else
                method = 3;
            end
            
            choice_2 = app.SaiSoButtonGroup.SelectedObject.Text;
            if(strcmp(choice_2,'O(h)'))
                e = 1;
            else
                e = 2;
            end
            
            if (method==1)&&(e==1)
                dfx0 = (f(x0+h) - f(x0))/h;
            elseif(method==1)&&(e==2)
                dfx0 = (-1*f(x0+2*h) + 4*f(x0+h) - 3*f(x0))/(2*h);
            elseif(method==2)&&(e==1)
                dfx0 = (f(x0) - f(x0-h))/h;
            elseif(method==2)&&(e==2)
                dfx0 = (3*f(x0) - 4*f(x0-h) + f(x0-2*h))/(2*h);
            else
                dfx0 = (f(x0+h) - f(x0-h))/(2*h);
            end
            dfx0 = round(dfx0,5);
            if(imag(dfx0)~=0)
                app.fx0EditField.Value = 'Syntax ERROR';
            else
                output = string(dfx0);
                app.fx0EditField.Value = output; 
            end
        end

        % Selection changed function: InputMethodDeriButtonGroup
        function InputMethodDeriButtonGroupSelectionChanged(app, event)
            switch app.InputMethodDeriButtonGroup.SelectedObject.Text
                case 'Nhập mảng'
                    app.UsingIntepolatePanel.Visible = 'on';
                    app.UsingFuncPanel.Visible = 'off';
                otherwise
                    app.UsingFuncPanel.Visible = 'on';
                    app.UsingIntepolatePanel.Visible = 'off';
            end
        end

        % Button pushed function: IntegralcalculationButton
        function IntegralcalculationButtonPushed(app, event)
            selectedButton = app.IntMethodButtonGroup.SelectedObject.Text;
            selectedButton_enter = app.InputMethodIntButtonGroup.SelectedObject.Text;
            a = app.enteraEditField.Value;
            b = app.enterbEditField.Value;
           
            switch selectedButton_enter
                case 'Nhập mảng'
                    X = app.xInputArray_IntEditField.Value;
                    Y = app.yInputArray_IntEditField.Value;
                    X = split(X,",");
                    xa = str2double(X);
                    Y = split(Y,",");
                    ya = str2double(Y);
                    syms x;         
                    n = length(xa);
                    sum = 0;
                     for i =1:n
                          product = ya(i);
                          for j =1:n
                              if(i ~= j)
                                    product = product * ((x - xa(j))/(xa(i)-xa(j)));
                              end
                         end
                     sum = sum + product;
                     end
                    funtion = sum;
                    funtion = str2func(['@(x)',char(funtion)]);
                otherwise
                     funtion = app.NhphmEditField.Value;
                     funtion = str2func(['@(x)',funtion]);
            end
            
            N = app.enterNEditField.Value;
            h = (b-a)/(N);
            f_a = funtion(a);
            f_b = funtion(b);
            
            switch selectedButton
                case 'Hình thang'
                    sum = 0;
                    for i =1: N-1
                            sum = sum + funtion(a+i*h);   
                    end
                    result = (h/2) * ((f_a + f_b)+ 2*sum);        
                case 'Simpson'             
                    sum1 = 0;
                    sum2 = 0;
                    for i =1: N-1
                        if mod(i,2) ~= 0
                            sum1 = sum1 + funtion(a+i*h);
                        else
                            sum2 = sum2 + funtion(a+i*h);
                        end
                    end
                    result = (h/3) * ((f_a + f_b)+ 4*sum1 +2*sum2);
                otherwise     
                    sum1 = 0;
                    sum2 = 0;
                    for i =1: N-1
                        if mod(i,3) ~= 0
                            sum1 = sum1 + funtion(a+i*h);
                        else
                            sum2 = sum2 + funtion(a+i*h);
                        end
                    end
                    result = ((3*h)/8) * ((f_a + f_b)+ 2*sum2 +3*sum1);
            end
            result = num2str(result);
            app.resultTextArea.Value = result;
        end

        % Selection changed function: InputMethodIntButtonGroup
        function InputMethodIntButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputMethodIntButtonGroup.SelectedObject.Text;
            switch selectedButton
            case 'Nhập mảng'
                app.Interpolate_IntPanel.Visible = 'on';
                app.Func_IntPanel.Visible = 'off';
            otherwise
                app.Interpolate_IntPanel.Visible = 'off';
                app.Func_IntPanel.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RootTab = uitab(app.TabGroup);
            app.RootTab.Title = 'Nghiệm';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Nội suy';
            app.RegressTab = uitab(app.TabGroup);
            app.RegressTab.Title = 'Hồi quy';
            app.DerivativeTab = uitab(app.TabGroup);
            app.DerivativeTab.Title = 'Đạo hàm';
            app.UsingIntepolatePanel = uipanel(app.DerivativeTab);
            app.UsingIntepolatePanel.Title = 'Dùng Nội suy';
            app.NhpxEditFieldLabel = uilabel(app.UsingIntepolatePanel);
            app.NhpxEditFieldLabel.Text = 'Nhập x';
            app.xInput_DeriEditField = uieditfield(app.UsingIntepolatePanel, 'text');
            app.NhpyLabel = uilabel(app.UsingIntepolatePanel);
            app.NhpyLabel.Text = 'Nhập y';
            app.yInput_DeriEditField = uieditfield(app.UsingIntepolatePanel, 'text');
            app.Ex_Label1 = uilabel(app.UsingIntepolatePanel);
            app.Ex_Label1.Text = 'Ví dụ: x = [1,2,3], ta nhập "1 2 3"';
            app.fx0EditFieldLabel = uilabel(app.DerivativeTab);
            app.fx0EditFieldLabel.Text = 'f''(x0)';
            app.fx0EditField = uieditfield(app.DerivativeTab, 'text');
            app.OKButton = uibutton(app.DerivativeTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UsingFuncPanel = uipanel(app.DerivativeTab);
            app.UsingFuncPanel.Title = 'Dùng biểu thức';
            app.UsingFuncPanel.Visible = 'off';
            app.NhphmsLabel = uilabel(app.UsingFuncPanel);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.fInput_DeriEditField = uieditfield(app.UsingFuncPanel, 'text');
            app.NhpbchLabel = uilabel(app.UsingFuncPanel);
            app.NhpbchLabel.Text = 'Nhập bước h';
            app.hInput_DeriEditField = uieditfield(app.UsingFuncPanel, 'text');
            app.Ex_Label2 = uilabel(app.UsingFuncPanel);
            app.Ex_Label2.Text = 'Ví dụ: y = 3.*x.^2 + (2/3).*x';
            app.SaiSoButtonGroup = uibuttongroup(app.DerivativeTab);
            app.SaiSoButtonGroup.Title = 'Giá trị sai số';
            app.OhButton = uiradiobutton(app.SaiSoButtonGroup);
            app.OhButton.Text = 'O(h)';
            app.OhButton.Value = true;
            app.Oh2Button = uiradiobutton(app.SaiSoButtonGroup);
            app.Oh2Button.Text = 'O(h^2)';
            app.DeriMethodButtonGroup = uibuttongroup(app.DerivativeTab);
            app.DeriMethodButtonGroup.Title = 'Phương pháp đạo hàm';
            app.XpxtinButton = uiradiobutton(app.DeriMethodButtonGroup);
            app.XpxtinButton.Text = 'Xấp xỉ tiến';
            app.XpxtinButton.Value = true;
            app.XpxliButton = uiradiobutton(app.DeriMethodButtonGroup);
            app.XpxliButton.Text = 'Xấp xỉ lùi';
            app.HngtmButton = uiradiobutton(app.DeriMethodButtonGroup);
            app.HngtmButton.Text = 'Hướng tâm';
            app.Gitrx0EditFieldLabel = uilabel(app.DerivativeTab);
            app.Gitrx0EditFieldLabel.Text = 'Giá trị x0';
            app.x0EditField = uieditfield(app.DerivativeTab, 'numeric');
            app.InputMethodDeriButtonGroup = uibuttongroup(app.DerivativeTab);
            app.InputMethodDeriButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputMethodDeriButtonGroupSelectionChanged, true);
            app.InputMethodDeriButtonGroup.Title = 'Phương thức nhập';
            app.InputArrayButton = uiradiobutton(app.InputMethodDeriButtonGroup);
            app.InputArrayButton.Text = 'Nhập mảng';
            app.InputArrayButton.Value = true;
            app.InputFuncButton = uiradiobutton(app.InputMethodDeriButtonGroup);
            app.InputFuncButton.Text = 'Nhập biểu thức';
            app.IntegralTab = uitab(app.TabGroup);
            app.IntegralTab.Title = 'Tích phân';
            app.IntegralcalculationButton = uibutton(app.IntegralTab, 'push');
            app.IntegralcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @IntegralcalculationButtonPushed, true);
            app.IntegralcalculationButton.Text = 'Integral calculation';
            app.InputMethodIntButtonGroup = uibuttongroup(app.IntegralTab);
            app.InputMethodIntButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputMethodIntButtonGroupSelectionChanged, true);
            app.InputMethodIntButtonGroup.Title = 'Cách nhập';
            app.ArrayInput_IntButton = uiradiobutton(app.InputMethodIntButtonGroup);
            app.ArrayInput_IntButton.Text = 'Nhập mảng';
            app.ArrayInput_IntButton.Value = true;
            app.FuncInput_IntButton = uiradiobutton(app.InputMethodIntButtonGroup);
            app.FuncInput_IntButton.Text = 'Nhập hàm';
            app.IntMethodButtonGroup = uibuttongroup(app.IntegralTab);
            app.IntMethodButtonGroup.Title = 'Phương pháp tính';
            app.hinhthangButton = uiradiobutton(app.IntMethodButtonGroup);
            app.hinhthangButton.Text = 'Hình thang';
            app.hinhthangButton.Value = true;
            app.SimpsonButton = uiradiobutton(app.IntMethodButtonGroup);
            app.SimpsonButton.Text = 'Simpson';
            app.Simpson3_8Button = uiradiobutton(app.IntMethodButtonGroup);
            app.Simpson3_8Button.Text = 'Simpson 3_8';
            app.resultTextAreaLabel = uilabel(app.IntegralTab);
            app.resultTextAreaLabel.Text = 'result';
            app.resultTextArea = uitextarea(app.IntegralTab);
            app.ThngsPanel = uipanel(app.IntegralTab);
            app.ThngsPanel.Title = 'Thông số';
            app.enteraEditFieldLabel = uilabel(app.ThngsPanel);
            app.enteraEditFieldLabel.Text = 'enter a';
            app.enteraEditField = uieditfield(app.ThngsPanel, 'numeric');
            app.enterbEditFieldLabel = uilabel(app.ThngsPanel);
            app.enterbEditFieldLabel.Text = 'enter b';
            app.enterbEditField = uieditfield(app.ThngsPanel, 'numeric');
            app.enterNEditFieldLabel = uilabel(app.ThngsPanel);
            app.enterNEditFieldLabel.Text = 'enter N';
            app.enterNEditField = uieditfield(app.ThngsPanel, 'numeric');
            app.Interpolate_IntPanel = uipanel(app.IntegralTab);
            app.Interpolate_IntPanel.Title = 'Dùng nội suy';
            app.NhpmngXLabel = uilabel(app.Interpolate_IntPanel);
            app.NhpmngXLabel.Text = 'Nhập mảng X';
            app.xInputArray_IntEditField = uieditfield(app.Interpolate_IntPanel, 'text');
            app.NhpmngYLabel = uilabel(app.Interpolate_IntPanel);
            app.NhpmngYLabel.Text = 'Nhập mảng Y';
            app.yInputArray_IntEditField = uieditfield(app.Interpolate_IntPanel, 'text');
            app.VdX123tanhp123Label = uilabel(app.Interpolate_IntPanel);
            app.VdX123tanhp123Label.Text = 'Ví dụ: X=[1,2,3], ta nhập "1,2,3"';
            app.Func_IntPanel = uipanel(app.IntegralTab);
            app.Func_IntPanel.Title = 'Dùng biểu thức';
            app.Func_IntPanel.Visible = 'off';
            app.NhphmEditFieldLabel = uilabel(app.Func_IntPanel);
            app.NhphmEditFieldLabel.Text = 'Nhập hàm';
            app.NhphmEditField = uieditfield(app.Func_IntPanel, 'text');
            app.Vdy2x232xLabel = uilabel(app.Func_IntPanel);
            app.Vdy2x232xLabel.Text = 'Ví dụ: y = 2.*x^2 + (3/2).*x';
            app.IntroduceTab = uitab(app.TabGroup);
            app.IntroduceTab.Title = 'Giới thiệu nhóm';
            app.GroupName = uilabel(app.IntroduceTab);
            app.GroupName.Visible = 'off';
            app.GroupName.Text = 'Hello! We''re the "Ghệ Đẹp" team!';
            app.UITable = uitable(app.IntroduceTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.ClickhereButton = uibutton(app.IntroduceTab, 'push');
            app.ClickhereButton.ButtonPushedFcn = createCallbackFcn(app, @ClickhereButtonPushed, true);
            app.ClickhereButton.Icon = 'alarm.gif';
            app.ClickhereButton.Text = 'Click here!';
            app.TeamLogo = uiimage(app.IntroduceTab);
            app.TeamLogo.Visible = 'off';
            app.TeamLogo.ImageSource = 'waving.gif';
            app.THISISAPRODUCTOFGHEDEPTEAMFORMOREINFOCLICKONTHEBUTTONBELOWLabel = uilabel(app.IntroduceTab);
            app.THISISAPRODUCTOFGHEDEPTEAMFORMOREINFOCLICKONTHEBUTTONBELOWLabel.Text = {'THIS IS A PRODUCT OF "GHE DEP" TEAM,'; 'FOR MORE INFO, CLICK ON THE BUTTON BELOW:'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end