classdef ProjectPPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        RootTab                      matlab.ui.container.Tab
        InterpolationTab             matlab.ui.container.Tab
        RegressTab                   matlab.ui.container.Tab
        DerivativeTab                matlab.ui.container.Tab
        Button2                      matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        IntegralTab                  matlab.ui.container.Tab
        nhap_YEditField              matlab.ui.control.EditField
        nhap_YEditField_2Label       matlab.ui.control.Label
        nhap_XEditField              matlab.ui.control.EditField
        nhap_XEditField_2Label       matlab.ui.control.Label
        phuong_phap_tinhButtonGroup  matlab.ui.container.ButtonGroup
        simpon3_8Button              matlab.ui.control.RadioButton
        simponButton                 matlab.ui.control.RadioButton
        hinhthangButton              matlab.ui.control.RadioButton
        Cach_nhap_hamButtonGroup     matlab.ui.container.ButtonGroup
        nhap_funtionButton           matlab.ui.control.RadioButton
        nhap_X_YButton               matlab.ui.control.RadioButton
        resultTextArea               matlab.ui.control.TextArea
        resultTextAreaLabel          matlab.ui.control.Label
        enterNEditField              matlab.ui.control.NumericEditField
        enterNEditFieldLabel         matlab.ui.control.Label
        IntegralcalculationButton    matlab.ui.control.Button
        enterafuntionEditField       matlab.ui.control.EditField
        enterafuntionEditFieldLabel  matlab.ui.control.Label
        enterbEditField              matlab.ui.control.NumericEditField
        enterbEditFieldLabel         matlab.ui.control.Label
        enteraEditField              matlab.ui.control.NumericEditField
        enteraEditFieldLabel         matlab.ui.control.Label
        IntroduceTab                 matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        NhmGhpLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IntegralcalculationButton
        function IntegralcalculationButtonPushed(app, event)
        selectedButton = app.phuong_phap_tinhButtonGroup.SelectedObject.Text;
        selectedButton_enter = app.Cach_nhap_hamButtonGroup.SelectedObject.Text;
            a = app.enteraEditField.Value;
            b = app.enterbEditField.Value;
           
            switch selectedButton_enter
                case 'nhap_X_Y'
                X = app.nhap_XEditField.Value;
                Y = app.nhap_YEditField.Value;
                X = split(X,",");
                xa = str2double(X);
                Y = split(Y,",");
                ya = str2double(Y);
                syms x;         
                n = length(xa);
                sum = 0;
         for i =1:n
              product = ya(i);
              for j =1:n
                  if(i ~= j)
                        product = product * ((x - xa(j))/(xa(i)-xa(j)));
                  end
             end
         sum = sum + product;
         end
        funtion = sum;
        funtion = str2func(['@(x)',char(funtion)]);
                case 'nhap_funtion'
                     funtion = app.enterafuntionEditField.Value;
                     funtion = str2func(['@(x)',funtion]);
            end

            
            N = app.enterNEditField.Value;
            h = (b-a)/(N);
            f_a = funtion(a);
            f_b = funtion(b);
            switch selectedButton
                case 'hinhthang'

            sum = 0;
            for i =1: N-1
               
                    sum = sum + funtion(a+i*h);
                
            end
            result = (h/2) * ((f_a + f_b)+ 2*sum) ;
         result = num2str(result);
            app.resultTextArea.Value = result;
            
                case 'simpon'
             
            sum1 = 0;
            sum2 = 0;
            for i =1: N-1
                if mod(i,2) ~= 0
                    sum1 = sum1 + funtion(a+i*h);
                else
                    sum2 = sum2 + funtion(a+i*h);
                end
            end
            result = (h/3) * ((f_a + f_b)+ 4*sum1 +2*sum2) ;
            result = num2str(result);
           app.resultTextArea.Value = result;
            
                     case 'simpon3_8'
                   
            sum1 = 0;
            sum2 = 0;
            for i =1: N-1
                if mod(i,3) ~= 0
                    sum1 = sum1 + funtion(a+i*h);
                else
                    sum2 = sum2 + funtion(a+i*h);
                end
            end
            result = ((3*h)/8) * ((f_a + f_b)+ 2*sum2 +3*sum1) ;
            result = num2str(result);
            app.resultTextArea.Value = result;
           
            end
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            
        end

        % Callback function
        function hinhthangButtonPushed(app, event)
                    
        end

        % Callback function
        function simpon3_8ButtonPushed(app, event)
             
        end

        % Selection changed function: Cach_nhap_hamButtonGroup
        function Cach_nhap_hamButtonGroupSelectionChanged(app, event)
            selectedButton = app.Cach_nhap_hamButtonGroup.SelectedObject.Text;
          switch selectedButton
              case 'nhap_funtion'
                  app.enterafuntionEditField.Visible = 'on' ;
                app.enterafuntionEditFieldLabel.Visible = 'on' ;
                  app.nhap_XEditField.Visible = 'off';
                 app.nhap_YEditField.Visible ='off';
                   app.nhap_XEditField_2Label.Visible = 'off';
                 app.nhap_YEditField_2Label.Visible ='off';
                 
               
              case 'nhap_X_Y'
                  app.enterafuntionEditField.Visible = 'off';
                  app.enterafuntionEditFieldLabel.Visible = 'off';
                 app.nhap_XEditField_2Label.Visible = 'on';
                 app.nhap_YEditField_2Label.Visible ='on';
                  app.nhap_XEditField.Visible = 'on';
                 app.nhap_YEditField.Visible ='on';

          end

            
        end

        % Size changed function: Cach_nhap_hamButtonGroup
        function Cach_nhap_hamButtonGroupSizeChanged(app, event)

        end

        % Button down function: Cach_nhap_hamButtonGroup
        function Cach_nhap_hamButtonGroupButtonDown(app, event)
            
        end

        % Selection changed function: phuong_phap_tinhButtonGroup
        function phuong_phap_tinhButtonGroupSelectionChanged(app, event)

        end

        % Value changed function: enterafuntionEditField
        function enterafuntionEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RootTab = uitab(app.TabGroup);
            app.RootTab.Title = 'Nghiệm';
            app.InterpolationTab = uitab(app.TabGroup);
            app.InterpolationTab.Title = 'Nội suy';
            app.RegressTab = uitab(app.TabGroup);
            app.RegressTab.Title = 'Hồi quy';
            app.DerivativeTab = uitab(app.TabGroup);
            app.DerivativeTab.Title = 'Đạo hàm';
            app.Button = uibutton(app.DerivativeTab, 'push');
            app.Button2 = uibutton(app.DerivativeTab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = 'Button2';
            app.IntegralTab = uitab(app.TabGroup);
            app.IntegralTab.Title = 'Tích phân';
            app.enteraEditFieldLabel = uilabel(app.IntegralTab);
            app.enteraEditFieldLabel.Text = 'enter a';
            app.enteraEditField = uieditfield(app.IntegralTab, 'numeric');
            app.enterbEditFieldLabel = uilabel(app.IntegralTab);
            app.enterbEditFieldLabel.Text = 'enter b';
            app.enterbEditField = uieditfield(app.IntegralTab, 'numeric');
            app.enterafuntionEditFieldLabel = uilabel(app.IntegralTab);
            app.enterafuntionEditFieldLabel.Visible = 'off';
            app.enterafuntionEditFieldLabel.Text = 'enter a funtion';
            app.enterafuntionEditField = uieditfield(app.IntegralTab, 'text');
            app.enterafuntionEditField.ValueChangedFcn = createCallbackFcn(app, @enterafuntionEditFieldValueChanged, true);
            app.enterafuntionEditField.Visible = 'off';
            app.IntegralcalculationButton = uibutton(app.IntegralTab, 'push');
            app.IntegralcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @IntegralcalculationButtonPushed, true);
            app.IntegralcalculationButton.Text = 'Integral calculation';
            app.enterNEditFieldLabel = uilabel(app.IntegralTab);
            app.enterNEditFieldLabel.Text = 'enter N';
            app.enterNEditField = uieditfield(app.IntegralTab, 'numeric');
            app.resultTextAreaLabel = uilabel(app.IntegralTab);
            app.resultTextAreaLabel.Text = 'result';
            app.resultTextArea = uitextarea(app.IntegralTab);
            app.Cach_nhap_hamButtonGroup = uibuttongroup(app.IntegralTab);
            app.Cach_nhap_hamButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Cach_nhap_hamButtonGroupSelectionChanged, true);
            app.Cach_nhap_hamButtonGroup.Title = 'Cach_nhap_ham';
            app.Cach_nhap_hamButtonGroup.SizeChangedFcn = createCallbackFcn(app, @Cach_nhap_hamButtonGroupSizeChanged, true);
            app.Cach_nhap_hamButtonGroup.ButtonDownFcn = createCallbackFcn(app, @Cach_nhap_hamButtonGroupButtonDown, true);
            app.nhap_X_YButton = uiradiobutton(app.Cach_nhap_hamButtonGroup);
            app.nhap_X_YButton.Text = 'nhap_X_Y';
            app.nhap_X_YButton.Value = true;
            app.nhap_funtionButton = uiradiobutton(app.Cach_nhap_hamButtonGroup);
            app.nhap_funtionButton.Text = 'nhap_funtion';
            app.phuong_phap_tinhButtonGroup = uibuttongroup(app.IntegralTab);
            app.phuong_phap_tinhButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @phuong_phap_tinhButtonGroupSelectionChanged, true);
            app.phuong_phap_tinhButtonGroup.Title = 'phuong_phap_tinh';
            app.hinhthangButton = uiradiobutton(app.phuong_phap_tinhButtonGroup);
            app.hinhthangButton.Text = 'hinhthang';
            app.hinhthangButton.Value = true;
            app.simponButton = uiradiobutton(app.phuong_phap_tinhButtonGroup);
            app.simponButton.Text = 'simpon';
            app.simpon3_8Button = uiradiobutton(app.phuong_phap_tinhButtonGroup);
            app.simpon3_8Button.Text = 'simpon3_8';
            app.nhap_XEditField_2Label = uilabel(app.IntegralTab);
            app.nhap_XEditField_2Label.Text = 'nhap_X';
            app.nhap_XEditField = uieditfield(app.IntegralTab, 'text');
            app.nhap_YEditField_2Label = uilabel(app.IntegralTab);
            app.nhap_YEditField_2Label.Text = 'nhap_Y';
            app.nhap_YEditField = uieditfield(app.IntegralTab, 'text');
            app.IntroduceTab = uitab(app.TabGroup);
            app.IntroduceTab.Title = 'Giới thiệu nhóm';
            app.NhmGhpLabel = uilabel(app.IntroduceTab);
            app.NhmGhpLabel.Text = 'Nhóm: Ghệ Đẹp';
            app.UITable = uitable(app.IntroduceTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end