classdef ProjectPPT_21200326 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NhpdliuPanel                  matlab.ui.container.Panel
        BmvoyButton                   matlab.ui.control.StateButton
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        DropDownOption                matlab.ui.control.DropDown
        xEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.EditField
        yEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.EditField
        KtquTextAreaLabel             matlab.ui.control.Label
        KtquTextArea                  matlab.ui.control.TextArea
        VthPanel                      matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        NhpButton                     matlab.ui.control.StateButton
        NotesTextAreaLabel            matlab.ui.control.Label
        NotesTextArea                 matlab.ui.control.TextArea
        DonPanel                      matlab.ui.container.Panel
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        NhpgitrcndonEditField         matlab.ui.control.EditField
        KtqudonTextAreaLabel          matlab.ui.control.Label
        KtqudonTextArea               matlab.ui.control.TextArea
        Button3                       matlab.ui.control.StateButton
    end

    
    methods (Access = private)
        
        function [a1, a0, r2] = Hoiquytuyentinh(app,x,y)
        n = length(x);
        sumx = 0;
        sumy = 0;
        sumxy = 0;
        sumx2 = 0;
        st = 0;
        sr = 0;
        for i = 1:n
            sumx = sumx + x(i);
            sumy = sumy + y(i);
            sumxy = sumxy + x(i)*y(i);
            sumx2 = sumx2 + x(i)*x(i);
        end
        xm = sumx/n;
        ym = sumy/n;
        a1 = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
        a0 = ym - a1*xm;
        for i = 1:n
            st = st + (y(i) - ym)^2;
            sr = sr + (y(i) - a1*x(i) - a0)^2;
        end
        r2 = (st - sr)/st;
        end  
      
        
        function [a, b, r2, X, Y] = Hoiquyhammux(app,x, y)
        X = log10(x);
        Y = log10(y);
        n = length(x);
        sumX = 0;
        sumY = 0;
        sumXY = 0;
        sumX2 = 0;
        st = 0;
        sr = 0;
        for i = 1:n
            sumX = sumX + X(i);
            sumY = sumY + Y(i);
            sumXY = sumXY + X(i)*Y(i);
            sumX2 = sumX2 + X(i)*X(i);
        end
        xm = sumX/n;
        ym = sumY/n;
        a1 = (n*sumXY - sumX*sumY)/(n*sumX2 - sumX*sumX);
        a0 = ym - a1*xm;
        a = 10^a0;
        b = a1;
        for i = 1:n
            st = st + (Y(i) - ym)^2;
            sr = sr + (Y(i) - a1*X(i) - a0)^2;
        end
         r2 = (st - sr)/st;
        end
        
        function [a, b, r2, Y] = Hoiquyhammue(app,x,y)
        Y = log(y);
        n = length(x);
        sumx = 0;
        sumY = 0;
        sumxY = 0;
        sumx2 = 0;
        st = 0;
        sr = 0;
        for i = 1:n
           sumx = sumx + x(i);
           sumY = sumY + Y(i);
           sumxY = sumxY + x(i)*Y(i);
           sumx2 = sumx2 + x(i)*x(i);
        end
       xm = sumx/n;
       ym = sumY/n;
       a1 = (n*sumxY - sumx*sumY)/(n*sumx2 - sumx*sumx);
       a0 = ym - a1*xm;
       a = exp(a0);
       b = a1;
        for i = 1:n
            st = st + (Y(i) - ym)^2;
            sr = sr + (Y(i) - a1*x(i) - a0)^2;
        end
        r2 = (st - sr)/st;
        end
        
       
        function [a1, a0, r2] = Hoiquylogarit(app, x, y)
        X = log(x);
        n = length(x);
        sumX = 0;
        sumy = 0;
        sumXy = 0;
        sumX2 = 0;
        st = 0;
        sr = 0;
        for i = 1:n
            sumX = sumX + X(i);
            sumy = sumy + y(i);
            sumXy = sumXy + X(i)*y(i);
            sumX2 = sumX2 +  X(i)*X(i);
        end
        xm = sumX/n;
        ym = sumy/n;
        a1 = (n*sumXy - sumX*sumy)/(n*sumX2 - sumX*sumX);
        a0 = ym - a1*xm;
        
        for i = 1:n
            st = st + (y(i) - ym)^2;
            sr = sr + (y(i) - a1*X(i) - a0)^2;
        end
         r2 = (st - sr)/st;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BmvoyButton
        function BmvoyButtonValueChanged(app, event)
        x = str2num(app.xEditField.Value);
        y = str2num(app.yEditField.Value);
        
        switch app.DropDownOption.Value
            case 'Tuyến tính'
                 [a1, a0, r2] = Hoiquytuyentinh(app, x, y);
                 app.KtquTextArea.Value = num2str([a1, a0, r2]);
                 app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %.4f + %.4f*x\nGiá trị tương quan r2 là: %.4f',a0, a1, r2);      
            case 'Hàm mũ x'
                [a, b, r2, X, Y] = Hoiquyhammux(app, x, y);
                app.KtquTextArea.Value = num2str([a, b, r2]);
                count = 0;
                for i=1:length(x)
                    if(imag(Y(i)) ~= 0 || imag(X(i)) ~= 0)
                        count = count + 1;
                    end
                end
                if(count==0)
                    app.KtquTextArea.Value = num2str([a, b, r2,X, Y]);
                    app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %f*x^%f\nGiá trị tương quan r2 là: %.4f',a, b,r2);
                else
                    count = 0;
                    app.KtquTextArea.Value = 'Mảng truyền vào không hợp lệ vì x hoặc y có chứa giá trị âm!';
                end
            case 'Hàm mũ e'
                [a, b, r2, Y] = Hoiquyhammue(app, x, y);
                app.KtquTextArea.Value = num2str([a, b, r2]);
%                 if(imag(Y)~=0)
                count = 0;
                for i=1:length(x)
                    if(imag(Y(i)) ~= 0)
                        count = count + 1;
                    end
                end
                if(count==0)
                    app.KtquTextArea.Value = num2str([a, b, r2, Y]);
                    app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %f*e^%f*x\nGiá trị tương quan r2 là: %.4f',a, b, r2);
                else
                    count = 0;
                    app.KtquTextArea.Value = 'Mảng truyền vào không hợp lệ vì y có chứa giá trị âm!';
                end
            otherwise
                [a1, a0, r2] = Hoiquylogarit(app, x, y);
                count = 0;
                for i=1:length(x)
                    if(x(i)<=0)
                        count = count + 1;
                    end
                end
                if(count==0)
                    app.KtquTextArea.Value = num2str([a1, a0, r2]);
                    app.KtquTextArea.Value = sprintf('Kết quả của PT: y = %.4f + %.4f*lnx\nGiá trị tương quan r2 là: %.4f',a0, a1, r2);
                else
                    count = 0;
                    app.KtquTextArea.Value = 'Mảng truyền vào có giá trị âm nên không hợp lệ!';
                end
        end
        end

        % Value changed function: NhpButton
        function NhpButtonValueChanged(app, event)
            %Vẽ Hình
          x = str2num(app.xEditField.Value);
          y = str2num(app.yEditField.Value);

    switch app.DropDownOption.Value
         case 'Tuyến tính'
             [a1, a0] = Hoiquytuyentinh(app, x, y);
             y_fit = a1.*x + a0; 
             plot(app.UIAxes, x, y, 'o', x, y_fit, '-');
         case 'Hàm mũ x'
             [a, b] = Hoiquyhammux(app, x, y);
             x1 = linspace(x(1),x(length(x))*5,1000);
             y_fit = a.*x1.^b;
             plot(app.UIAxes, x, y, 'o', x1, y_fit, '-');
         case 'Hàm mũ e'
             [a, b] = Hoiquyhammue(app, x, y);
             x1 = linspace(x(1),x(length(x))*5,1000);
             y_fit = a.*exp(b.*x1);
             plot(app.UIAxes, x, y, 'o', x1, y_fit, '-');
        otherwise
            [a1, a0] = Hoiquylogarit(app, x, y);
            x1 = linspace(0,x(length(x))*5,1000);   
            y_fit = a0 + a1.*log(x1);
            plot(app.UIAxes, x, y, 'o', x1, y_fit, '-');
    end
            
        end

        % Value changed function: Button3
        function Button3ValueChanged(app, event)
          t = str2num(app.NhpgitrcndonEditField.Value);
          x = str2num(app.xEditField.Value);
          y = str2num(app.yEditField.Value);
     switch app.DropDownOption.Value
         case 'Tuyến tính'
             [a1, a0] = Hoiquytuyentinh(app, x, y);
             predict_y = a1.*t + a0; 
         case 'Hàm mũ x'
             [a, b] = Hoiquyhammux(app, x, y);
             predict_y = a.*t.^b;   
         case 'Hàm mũ e'    
             [a, b] = Hoiquyhammue(app, x, y);
             predict_y = a.*exp(b.*t);
         otherwise
             [a1, a0] = Hoiquylogarit(app, x, y);
             predict_y = a0 + a1.*log(t);
     end
            
          app.KtqudonTextArea.Value = sprintf('Giá trị cần dự đoán là: %.4f',predict_y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NhpdliuPanel = uipanel(app.UIFigure);
            app.NhpdliuPanel.Title = 'Nhập dữ liệu';
            app.BmvoyButton = uibutton(app.NhpdliuPanel, 'state');
            app.BmvoyButton.ValueChangedFcn = createCallbackFcn(app, @BmvoyButtonValueChanged, true);
            app.BmvoyButton.Text = 'Bấm vào đây';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.NhpdliuPanel);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.DropDownOption = uidropdown(app.NhpdliuPanel);
            app.DropDownOption.Items = {'Tuyến tính', 'Hàm mũ x', 'Hàm mũ e', 'Logarit'};
            app.DropDownOption.Value = 'Tuyến tính';
            app.xEditFieldLabel = uilabel(app.NhpdliuPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NhpdliuPanel, 'text');
            app.yEditFieldLabel = uilabel(app.NhpdliuPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NhpdliuPanel, 'text');
            app.KtquTextAreaLabel = uilabel(app.NhpdliuPanel);
            app.KtquTextAreaLabel.Text = 'Kết quả';
            app.KtquTextArea = uitextarea(app.NhpdliuPanel);
            app.VthPanel = uipanel(app.UIFigure);
            app.VthPanel.Title = 'Vẽ đồ thị';
            app.UIAxes = uiaxes(app.VthPanel);
            title(app.UIAxes, 'Đồ thị hồi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NhpButton = uibutton(app.VthPanel, 'state');
            app.NhpButton.ValueChangedFcn = createCallbackFcn(app, @NhpButtonValueChanged, true);
            app.NhpButton.Text = 'Nhấp!!!';
            app.NotesTextAreaLabel = uilabel(app.UIFigure);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.UIFigure);
            app.NotesTextArea.Value = {'1. Hai mảng phải cùng kích thước. VD: x = 1 2 3'; '                   y = 4 5 6'; ''};
            app.DonPanel = uipanel(app.UIFigure);
            app.DonPanel.Title = 'Dự đoán';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.DonPanel);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.DonPanel, 'text');
            app.KtqudonTextAreaLabel = uilabel(app.DonPanel);
            app.KtqudonTextAreaLabel.Text = 'Kết quả dự đoán';
            app.KtqudonTextArea = uitextarea(app.DonPanel);
            app.Button3 = uibutton(app.DonPanel, 'state');
            app.Button3.ValueChangedFcn = createCallbackFcn(app, @Button3ValueChanged, true);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT_21200326

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end