classdef ProjectPPT_21200327 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TextArea             matlab.ui.control.TextArea
        NGHIMEditField       matlab.ui.control.NumericEditField
        NGHIMEditFieldLabel  matlab.ui.control.Label
        SLNEditField         matlab.ui.control.NumericEditField
        SLNEditFieldLabel    matlab.ui.control.Label
        XCNHNButton          matlab.ui.control.Button
        B                    matlab.ui.control.EditField
        NhpbLabel            matlab.ui.control.Label
        SAISO                matlab.ui.control.EditField
        SaisLabel            matlab.ui.control.Label
        A                    matlab.ui.control.EditField
        NhpaLabel            matlab.ui.control.Label
        NhapHam              matlab.ui.control.EditField
        NhphmfxLabel         matlab.ui.control.Label
        ChonPP               matlab.ui.container.ButtonGroup
        TiepTuyen            matlab.ui.control.RadioButton
        Lap                  matlab.ui.control.RadioButton
        ChiaDoi              matlab.ui.control.RadioButton
        Note                 matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [nghiem, solanlap] = Chiadoi(app,f, a, b, saiso)
                       solanlap = 0;
                       nghiem = 0;
                        e = b - a;
                        while e > saiso
                            c = (a+b)/2;
                            if f(a)*f(c) < 0
                                b = c;
                                e = b - a;
                            else
                                a = c;
                                e = b - a;
                            end
                            solanlap = solanlap + 1;
                        end
                        nghiem = a;
            
        end
        
        function [nghiem,solanlap,KHT] = lap(app,fpi,a,b,saiso)
                    solanlap = 0;
                    nghiem = 0;  
                    KHT = 0;
                    x0 = a; 
                    x1 = b;
                    while(abs(x1 - x0) >= saiso)
                        temp = x1; 
                        x1 = double(fpi(x0));     
                        x0 = temp;
                        solanlap = solanlap + 1;
                        if (x1 >b || x1<a)
                            KHT = 1;
                            break;
                        end
                        
                    end
                    nghiem = x1;
            
        end
        
        function [nghiem, solanlap] = tieptuyen(app,f, a, b, saiso)
                    syms x;
                    fd1 = str2func(['@(x)' char(diff(f(x)))]);
                    fd2 = str2func(['@(x)' char(diff(fd1(x)))]);
                    solanlap = 0;
                    nghiem = 0;
                    s = 0.1;
                   while a < b
                       if fd1(a)*fd1(a + s) <= 0 && fd2(a)*fd2(a + s) <= 0
                        break;
                       else
                           a = a + s;
                           b = b - s;          
                       end
                  end
                   x0 = a;
                   y = 0;
                    while(y == 0)
                        c = fd1(x0)*fd2(x0);
                        if c > 0            
                            while(1)
                                x1 = x0 - f(x0)/fd1(x0); 
                                if(abs(x1 - x0) < saiso)
                                nghiem = x1;
                                y = 1;
                                break;              
                                end
                        solanlap = solanlap + 1;
                        x0 = x1;
                            end
                        else 
                            x0 = (x0+b)/2;               
                        end
                    end 
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ChonPP
        function ChnphngphpButtonGroupSelectionChanged(app, event)
          
            switch app.ChonPP.SelectedObject.Text
                case "Lặp"
                    app.NhphmfxLabel.Text = 'Nhập hàm lặp fp(x)';
                    app.Note.Visible = 'on';
                    app.TextArea.Value = sprintf("");
                otherwise
                    app.NhphmfxLabel.Text = 'Nhập hàm f(x)';
                    app.Note.Visible = 'off';
                    app.TextArea.Value = sprintf("");
            end
            
        end

        % Button pushed function: XCNHNButton
        function XCNHNButtonPushed(app, event)
            saiso = app.SAISO.Value;           
            a = app.A.Value;
            b = app.B.Value;
            fa = app.NhapHam.Value;           
            syms  x;
            if(fa==""||a==""||b==""||saiso=="")
                app.TextArea.Value = sprintf("Không được để trống các ô này");
            else  
                a=str2num(a);
                b = str2num(b);
                saiso = str2num(saiso);
                f = str2func(['@(x)',fa]);    
                switch app.ChonPP.SelectedObject.Text
                case "Chia đôi"
                    [nghiem, solanlap] = Chiadoi(app,f, a, b, saiso);
                    app.SLNEditField.Value = solanlap;
                    app.NGHIMEditField.Value = double(nghiem);
                        
                case "Lặp"
                    [nghiem, solanlap,KHT] = lap(app,f, a, b, saiso);
                    if(KHT == 1)
                        app.TextArea.Value = sprintf("HÀM KHÔNG HỘI TỤ, VUI LÒNG CHỌN HÀM LẶP KHÁC");
                        app.SLNEditField.Value = 0;
                        app.NGHIMEditField.Value = 0;
                    else
                        app.SLNEditField.Value = solanlap;
                        app.NGHIMEditField.Value = nghiem;
                    end


                case "Tiếp tuyến (Newton)"                   
                    [nghiem, solanlap] = tieptuyen(app,f, a, b, saiso)   
                    app.SLNEditField.Value = solanlap;
                    app.NGHIMEditField.Value = nghiem;
                end
                
            X = a:0.05:b;
            S = f(X);
           
            if (app.ChonPP.SelectedObject.Text == "Lặp"&& KHT == 0)               
                Y = @(x) x;
                L = Y(X)
                plot(app.UIAxes, X, L,X, S);
                
            elseif (app.ChonPP.SelectedObject.Text == "Lặp"&& KHT == 1)
                plot(app.UIAxes,0,0.*x);
            else
                 plot(app.UIAxes, X, S);   
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ĐỒ THỊ PHƯƠNG TRÌNH')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Note = uilabel(app.UIFigure);
            app.Note.Visible = 'off';
            app.Note.Text = {'* Note: Khi dùng phương pháp lặp cần chọn phương trình lặp'; ' thoả điều kiện hội tụ:'; '- (a, b) là khoảng phân ly nghiệm của phương trình '; '(1) : fx = 0. Nếu (1) ⇔ φ(x) mà φ(x) thoả 3 điều kiện:'; '+ φ(x) và φ''(x) cùng liên tục trong khoảng (a, b).'; '+ φ(x) ∈ (a, b), ∀x ∈ (a, b).'; '+ φ''(x) ≤ q  < 1, ∀x ∈ (a, b).'};
            app.ChonPP = uibuttongroup(app.UIFigure);
            app.ChonPP.SelectionChangedFcn = createCallbackFcn(app, @ChnphngphpButtonGroupSelectionChanged, true);
            app.ChonPP.Title = 'Chọn phương pháp';
            app.ChiaDoi = uiradiobutton(app.ChonPP);
            app.ChiaDoi.Text = 'Chia đôi';
            app.ChiaDoi.Value = true;
            app.Lap = uiradiobutton(app.ChonPP);
            app.Lap.Text = 'Lặp';
            app.TiepTuyen = uiradiobutton(app.ChonPP);
            app.TiepTuyen.Text = 'Tiếp tuyến (Newton)';
            app.NhphmfxLabel = uilabel(app.UIFigure);
            app.NhphmfxLabel.Text = 'Nhập hàm f(x)';
            app.NhapHam = uieditfield(app.UIFigure, 'text');
            app.NhpaLabel = uilabel(app.UIFigure);
            app.NhpaLabel.Text = 'Nhập a';
            app.A = uieditfield(app.UIFigure, 'text');
            app.SaisLabel = uilabel(app.UIFigure);
            app.SaisLabel.Text = 'Sai số';
            app.SAISO = uieditfield(app.UIFigure, 'text');
            app.NhpbLabel = uilabel(app.UIFigure);
            app.NhpbLabel.Text = 'Nhập b';
            app.B = uieditfield(app.UIFigure, 'text');
            app.XCNHNButton = uibutton(app.UIFigure, 'push');
            app.XCNHNButton.ButtonPushedFcn = createCallbackFcn(app, @XCNHNButtonPushed, true);
            app.XCNHNButton.Text = 'XÁC NHẬN';
            app.SLNEditFieldLabel = uilabel(app.UIFigure);
            app.SLNEditFieldLabel.Text = 'SỐ LẦN';
            app.SLNEditField = uieditfield(app.UIFigure, 'numeric');
            app.NGHIMEditFieldLabel = uilabel(app.UIFigure);
            app.NGHIMEditFieldLabel.Text = 'NGHIỆM';
            app.NGHIMEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPPT_21200327

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end